classdef DIsplayIR_app_stereo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Phase                      matlab.ui.control.UIAxes
        GroupDelay                 matlab.ui.control.UIAxes
        AWeightingButton           matlab.ui.control.Button
        PlotDistorsionDataButton   matlab.ui.control.Button
        PlotImpulseResponseButton  matlab.ui.control.Button
        PlotWaterfallButton        matlab.ui.control.Button
        PlotRT60Button             matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        Freq                       matlab.ui.control.UIAxes
        Phase_2                    matlab.ui.control.UIAxes
        GroupDelay_2               matlab.ui.control.UIAxes
        Freq_2                     matlab.ui.control.UIAxes
        Harmonic2Button            matlab.ui.control.Button
        Harmonic3Button            matlab.ui.control.Button
        Harmonic4Button            matlab.ui.control.Button
        THDButton                  matlab.ui.control.Button
        THDnoiseButton             matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        AQMButton                  matlab.ui.control.Button
        GDAQMButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global h2l
            global h2r
            global h3l
            global h3r
            global h4l
            global h4r
            global thd_noise_l
            global thd_noise_r
            global thd_l
            global thd_r
            global unsmooth_faxis
            global ft_wins_hann1
            global ft_wins_hann2
            global THD2
            global THD1
            global ft_wins_hann_noise1
            global ft_wins_hann_noise2
            
            freq = evalin('base','freq');
            freq_bark = evalin('base','freq_bark');
            
            group_delay_left = evalin('base','group_delay_left');
            group_delay_bark_left = evalin('base','group_delay_bark_left');
            myphase_left = evalin('base','myphase_left');
            phase_bark_left = evalin('base','phase_bark_left');
            db_left = evalin('base','db_left');
            mag_bark_left = evalin('base','mag_bark_left');
            
            group_delay_right = evalin('base','group_delay_right');
            group_delay_bark_right = evalin('base','group_delay_bark_right');
            myphase_right = evalin('base','myphase_right');
            phase_bark_right = evalin('base','phase_bark_right');
            db_right = evalin('base','db_right');
            mag_bark_right = evalin('base','mag_bark_right');
            
            Harmonics_app_stereo_test();
            
            ft_wins_hann1 = evalin('base','ft_wins_hann1');
            unsmooth_faxis = evalin('base','unsmooth_faxis');
            ft_wins_hann2 = evalin('base','ft_wins_hann2');
            
            THD1 = evalin('base','THD1');
            THD2 = evalin('base','THD2');
            ft_wins_hann_noise1 = evalin('base','ft_wins_hann_noise1');
            ft_wins_hann_noise2 = evalin('base','ft_wins_hann_noise2');
            
            
            hold(app.Freq,'on')
            plot(app.Freq, freq, db_left, 'DisplayName', 'Fundamental');
            plot(app.Freq, freq_bark, mag_bark_left, 'DisplayName', 'Fundamental-Bark');
            h2l = plot(app.Freq,unsmooth_faxis, ft_wins_hann1{2}','LineWidth',0.75,'DisplayName','Harmonic 2');
            set(h2l,'Visible','off')
            h3l = plot(app.Freq,unsmooth_faxis, ft_wins_hann1{3}','LineWidth',0.75,'DisplayName','Harmonic 3');
            set(h3l,'Visible','off')
            h4l = plot(app.Freq,unsmooth_faxis, ft_wins_hann1{4}','LineWidth',0.75,'DisplayName','Harmonic 4');
            set(h4l,'Visible','off')
            thd_noise_l = plot(app.Freq,unsmooth_faxis, ft_wins_hann_noise1','LineWidth',0.75,'DisplayName','THD+noise');
            set(thd_noise_l,'Visible','off')
            thd_l = plot(app.Freq, THD1','LineWidth',0.75,'DisplayName','THD');
            set(thd_l,'Visible','off')
            hold(app.Freq,'off')

            hold(app.Freq_2,'on')
            plot(app.Freq_2, freq, db_right, 'DisplayName', 'Fundamental');
            plot(app.Freq_2, freq_bark, mag_bark_right, 'DisplayName', 'Fundamental-Bark');
            h2r = plot(app.Freq_2,unsmooth_faxis, ft_wins_hann2{2}','LineWidth',0.75,'DisplayName','Harmonic 2');
            set(h2r,'Visible','off')
            h3r = plot(app.Freq_2,unsmooth_faxis, ft_wins_hann2{3}','LineWidth',0.75,'DisplayName','Harmonic 3');
            set(h3r,'Visible','off')
            h4r = plot(app.Freq_2,unsmooth_faxis, ft_wins_hann2{4}','LineWidth',0.75,'DisplayName','Harmonic 4');
            set(h4r,'Visible','off')
            thd_noise_r = plot(app.Freq_2,unsmooth_faxis, ft_wins_hann_noise2','LineWidth',0.75,'DisplayName','THD+noise');
            set(thd_noise_r,'Visible','off')
            thd_r = plot(app.Freq_2, THD2','LineWidth',0.75,'DisplayName','THD');
            set(thd_r,'Visible','off')
            hold(app.Freq_2,'off')
            
            hold(app.Phase,'on')
            plot(app.Phase, freq, myphase_left);
            plot(app.Phase, freq_bark, phase_bark_left);
            hold(app.Phase,'off')
            
            hold(app.Phase_2,'on')
            plot(app.Phase_2, freq, myphase_right);
            plot(app.Phase_2, freq_bark, phase_bark_right);
            hold(app.Phase_2,'off')
            
            hold(app.GroupDelay,'on')
            plot(app.GroupDelay, freq, group_delay_left);
            plot(app.GroupDelay, freq_bark, group_delay_bark_left);
            hold(app.GroupDelay,'off')
            
            hold(app.GroupDelay_2,'on')
            plot(app.GroupDelay_2, freq, group_delay_right);
            plot(app.GroupDelay_2, freq_bark, group_delay_bark_right);
            hold(app.GroupDelay_2,'off')
            
            legend(app.Freq_2,'show','location','best')
            legend(app.Freq,'show','location','best')
        end

        % Button pushed function: PlotImpulseResponseButton
        function PlotImpulseResponseButtonPushed(app, event)
            wavprint('plotfig');
        end

        % Button pushed function: PlotDistorsionDataButton
        function PlotDistorsionDataButtonPushed(app, event)
            plotdist('plotfig');
        end

        % Button pushed function: PlotWaterfallButton
        function PlotWaterfallButtonPushed(app, event)
            plotwaterfall('plotfig');
        end

        % Button pushed function: PlotRT60Button
        function PlotRT60ButtonPushed(app, event)
            RT60('plotfig');
        end

        % Button pushed function: AWeightingButton
        function AWeightingButtonPushed(app, event)
            plot_a_weight('plotfig');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global h2l
            global h2r
            global h3l
            global h3r
            global h4l
            global h4r
            global thd_noise_l
            global thd_noise_r
            global thd_l
            global thd_r
            
            set(thd_noise_l,'Visible','off')
            set(thd_noise_r,'Visible','off')
            set(thd_l,'Visible','off')
            set(thd_r,'Visible','off')
            set(h4l,'Visible','off')
            set(h4r,'Visible','off')
            set(h3l,'Visible','off')
            set(h3r,'Visible','off')
            set(h2l,'Visible','off')
            set(h2r,'Visible','off')
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.UIFigure);
            close all;
            AS_App;
        end

        % Button pushed function: Harmonic2Button
        function Harmonic2ButtonPushed(app, event)
            global h2l
            global h2r
            set(h2l,'Visible','on')
            set(h2r,'Visible','on')
        end

        % Button pushed function: Harmonic3Button
        function Harmonic3ButtonPushed(app, event)
            global h3l
            global h3r
            set(h3l,'Visible','on')
            set(h3r,'Visible','on')
        end

        % Button pushed function: Harmonic4Button
        function Harmonic4ButtonPushed(app, event)
            global h4l
            global h4r
            set(h4l,'Visible','on')
            set(h4r,'Visible','on')
        end

        % Button pushed function: THDButton
        function THDButtonPushed(app, event)
            global thd_l
            global thd_r
            set(thd_l,'Visible','on')
            set(thd_r,'Visible','on')
        end

        % Button pushed function: THDnoiseButton
        function THDnoiseButtonPushed(app, event)
            global thd_noise_l
            global thd_noise_r
            set(thd_noise_l,'Visible','on')
            set(thd_noise_r,'Visible','on')
        end

        % Button pushed function: AQMButton
        function AQMButtonPushed(app, event)
            setpath;
            current = AppConversion;
            IR_path = evalin('base', 'IR_path');
            cd(IR_path);
            IR_file = evalin('base', 'IR_file');
            measuredSignalinWav = IR_file;
            
            %measuredSignalinWav = 'electronic(1)_convolvedwithireqaualised_iir.wav';
            amp_aqm_app = AmpAQM(measuredSignalinWav,0,16);
            assignin('base', 'amp_aqm_app', amp_aqm_app);
            message = sprintf(['The amplitude metric is ', num2str(amp_aqm_app)]);
            uialert(app.UIFigure, message,'Amplitude AQM');
            
            cd(current);
        end

        % Button pushed function: GDAQMButton
        function GDAQMButtonPushed(app, event)
            setpath;
            current = AppConversion;
            IR_path = evalin('base', 'IR_path');
            cd(IR_path);
            IR_file = evalin('base', 'IR_file');
            measuredSignalinWav = IR_file; 
                        
           %measuredSignalinWav = 'electronic(1)_convolvedwithireqaualised_iir.wav';
            gd_aqm_app = GDAQM(measuredSignalinWav);
            assignin('base', 'gd', gd_aqm_app);
            message = sprintf(['The GD metric is ', num2str(gd_aqm_app)]);
            uialert(app.UIFigure, message,'GD AQM');
            
            cd(current);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Phase = uiaxes(app.UIFigure);
            title(app.Phase, 'Phase Response')
            xlabel(app.Phase, 'Frequency (Hz)')
            ylabel(app.Phase, 'Phase (Degrees)')
            app.Phase.XLim = [10 20000];
            app.Phase.YLim = [-500000 500000];
            app.Phase.XScale = 'log';
            app.GroupDelay = uiaxes(app.UIFigure);
            title(app.GroupDelay, 'Group Delay')
            xlabel(app.GroupDelay, 'Frequency (Hz)')
            ylabel(app.GroupDelay, 'Group Delay (ms)')
            app.GroupDelay.XLim = [10 20000];
            app.GroupDelay.YLim = [0 100];
            app.GroupDelay.XScale = 'log';
            app.AWeightingButton = uibutton(app.UIFigure, 'push');
            app.AWeightingButton.ButtonPushedFcn = createCallbackFcn(app, @AWeightingButtonPushed, true);
            app.AWeightingButton.Text = 'A-Weighting';
            app.PlotDistorsionDataButton = uibutton(app.UIFigure, 'push');
            app.PlotDistorsionDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDistorsionDataButtonPushed, true);
            app.PlotDistorsionDataButton.Text = 'Plot Distorsion Data';
            app.PlotImpulseResponseButton = uibutton(app.UIFigure, 'push');
            app.PlotImpulseResponseButton.ButtonPushedFcn = createCallbackFcn(app, @PlotImpulseResponseButtonPushed, true);
            app.PlotImpulseResponseButton.Text = 'Plot Impulse Response';
            app.PlotWaterfallButton = uibutton(app.UIFigure, 'push');
            app.PlotWaterfallButton.ButtonPushedFcn = createCallbackFcn(app, @PlotWaterfallButtonPushed, true);
            app.PlotWaterfallButton.Text = 'Plot Waterfall';
            app.PlotRT60Button = uibutton(app.UIFigure, 'push');
            app.PlotRT60Button.ButtonPushedFcn = createCallbackFcn(app, @PlotRT60ButtonPushed, true);
            app.PlotRT60Button.Text = 'Plot RT60';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Freq = uiaxes(app.UIFigure);
            title(app.Freq, 'RTF Left Channel')
            xlabel(app.Freq, 'Frequency (Hz)')
            ylabel(app.Freq, 'Amplitude (dB)')
            app.Freq.XLim = [10 20000];
            app.Freq.YLim = [-100 50];
            app.Freq.XScale = 'log';
            app.Phase_2 = uiaxes(app.UIFigure);
            title(app.Phase_2, 'Phase Response')
            xlabel(app.Phase_2, 'Frequency (Hz)')
            ylabel(app.Phase_2, 'Phase (Degrees)')
            app.Phase_2.XLim = [10 20000];
            app.Phase_2.YLim = [-500000 500000];
            app.Phase_2.XScale = 'log';
            app.GroupDelay_2 = uiaxes(app.UIFigure);
            title(app.GroupDelay_2, 'Group Delay')
            xlabel(app.GroupDelay_2, 'Frequency (Hz)')
            ylabel(app.GroupDelay_2, 'Group Delay (ms)')
            app.GroupDelay_2.XLim = [10 20000];
            app.GroupDelay_2.YLim = [0 100];
            app.GroupDelay_2.XScale = 'log';
            app.Freq_2 = uiaxes(app.UIFigure);
            title(app.Freq_2, 'RTF Right Channel')
            xlabel(app.Freq_2, 'Frequency (Hz)')
            ylabel(app.Freq_2, 'Amplitude (dB)')
            app.Freq_2.XLim = [10 20000];
            app.Freq_2.YLim = [-100 50];
            app.Freq_2.XScale = 'log';
            app.Harmonic2Button = uibutton(app.UIFigure, 'push');
            app.Harmonic2Button.ButtonPushedFcn = createCallbackFcn(app, @Harmonic2ButtonPushed, true);
            app.Harmonic2Button.Text = 'Harmonic 2';
            app.Harmonic3Button = uibutton(app.UIFigure, 'push');
            app.Harmonic3Button.ButtonPushedFcn = createCallbackFcn(app, @Harmonic3ButtonPushed, true);
            app.Harmonic3Button.Text = 'Harmonic 3';
            app.Harmonic4Button = uibutton(app.UIFigure, 'push');
            app.Harmonic4Button.ButtonPushedFcn = createCallbackFcn(app, @Harmonic4ButtonPushed, true);
            app.Harmonic4Button.Text = 'Harmonic 4';
            app.THDButton = uibutton(app.UIFigure, 'push');
            app.THDButton.ButtonPushedFcn = createCallbackFcn(app, @THDButtonPushed, true);
            app.THDButton.Text = 'THD';
            app.THDnoiseButton = uibutton(app.UIFigure, 'push');
            app.THDnoiseButton.ButtonPushedFcn = createCallbackFcn(app, @THDnoiseButtonPushed, true);
            app.THDnoiseButton.Text = 'THD + noise';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AQMButton = uibutton(app.UIFigure, 'push');
            app.AQMButton.ButtonPushedFcn = createCallbackFcn(app, @AQMButtonPushed, true);
            app.AQMButton.Text = 'AQM';
            app.GDAQMButton = uibutton(app.UIFigure, 'push');
            app.GDAQMButton.ButtonPushedFcn = createCallbackFcn(app, @GDAQMButtonPushed, true);
            app.GDAQMButton.Text = 'GDAQM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DIsplayIR_app_stereo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end