classdef DisplayIR_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Phase                      matlab.ui.control.UIAxes
        GroupDelay                 matlab.ui.control.UIAxes
        AWeightingButton           matlab.ui.control.Button
        PlotDistortionDataButton   matlab.ui.control.Button
        PlotImpulseResponseButton  matlab.ui.control.Button
        PlotWaterfallButton        matlab.ui.control.Button
        PlotRT60Button             matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        Freq                       matlab.ui.control.UIAxes
        Harmonic2Button            matlab.ui.control.Button
        Harmonic3Button            matlab.ui.control.Button
        Harmonic4Button            matlab.ui.control.Button
        THDnoiseButton             matlab.ui.control.Button
        THDButton                  matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global ft_wins_hann
            global ft_wins_hann_noise
            global THD
            global unsmooth_faxis;
            global h2
            global h3 
            global h4  
            global THD_noise
            global THD_curve
            
            mOrs = evalin('base','mOrs');
            
            if strcmp(mOrs,'mono-left')
                freq = evalin('base','freq');
                group_delay = evalin('base','group_delay_left');
                freq_bark = evalin('base','freq_bark');
                group_delay_bark = evalin('base','group_delay_bark_left');
                myphase = evalin('base','myphase_left');
                phase_bark = evalin('base','phase_bark_left');
                db = evalin('base','db_left');
                mag_bark = evalin('base','mag_bark_left');
            else
                freq = evalin('base','freq');
                group_delay = evalin('base','group_delay_right');
                freq_bark = evalin('base','freq_bark');
                group_delay_bark = evalin('base','group_delay_bark_right');
                myphase = evalin('base','myphase_right');
                phase_bark = evalin('base','phase_bark_right');
                db = evalin('base','db_right');
                mag_bark = evalin('base','mag_bark_right');
            end
            
            Harmonics_app();
            
            ft_wins_hann = evalin('base','ft_wins_hann');            
            ft_wins_hann_noise = evalin('base','ft_wins_hann_noise');            
            THD = evalin('base','THD');            
            unsmooth_faxis = evalin('base','unsmooth_faxis');
            
            hold (app.Freq,'on')
            plot(app.Freq, freq, db, 'DisplayName', 'Fundamental');
            plot(app.Freq, freq_bark, mag_bark, 'DisplayName', 'Fundamental-Bark');
            hold off
            
            hold (app.Phase,'on')
            plot(app.Phase, freq, myphase);
            plot(app.Phase, freq_bark, phase_bark);
            hold off
            
            hold (app.GroupDelay,'on')
            plot(app.GroupDelay, freq, group_delay);
            plot(app.GroupDelay, freq_bark, group_delay_bark);
            hold off
            
            % Making the Harmonics and THD curves invisible
            h2 = plot(app.Freq,unsmooth_faxis, ft_wins_hann{2},'LineWidth',0.75,'DisplayName','Harmonic 2');
            set(h2,'Visible','off')
                    
            h3 = plot(app.Freq,unsmooth_faxis, ft_wins_hann{3},'LineWidth',0.75,'DisplayName','Harmonic 3');
            set(h3,'Visible','off')
                    
            h4 = plot(app.Freq,unsmooth_faxis, ft_wins_hann{4},'LineWidth',0.75,'DisplayName','Harmonic 4');
            set(h4,'Visible','off')
            
            THD_noise = plot(app.Freq,unsmooth_faxis, ft_wins_hann_noise,'LineWidth',0.75, 'DisplayName','THD+noise');
            set(THD_noise,'Visible','off')
            
            THD_curve = plot(app.Freq,THD,'LineWidth',0.75, 'DisplayName','THD');
            set(THD_curve,'Visible','off')
            
            legend(app.Freq,'show')
        end

        % Button pushed function: PlotImpulseResponseButton
        function PlotImpulseResponseButtonPushed(app, event)
            wavprint('plotfig');
        end

        % Button pushed function: PlotDistortionDataButton
        function PlotDistortionDataButtonPushed(app, event)
            plotdist('plotfig');
        end

        % Button pushed function: PlotWaterfallButton
        function PlotWaterfallButtonPushed(app, event)
            plotwaterfall('plotfig');
        end

        % Button pushed function: PlotRT60Button
        function PlotRT60ButtonPushed(app, event)
            RT60('plotfig');
        end

        % Button pushed function: AWeightingButton
        function AWeightingButtonPushed(app, event)
            plot_a_weight('plotfig');
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.UIFigure);
            close all;
            AS_App;
        end

        % Button pushed function: Harmonic2Button
        function Harmonic2ButtonPushed(app, event)
            global h2            
            set(h2,'Visible','on')
        end

        % Button pushed function: Harmonic3Button
        function Harmonic3ButtonPushed(app, event)
            global h3            
            set(h3,'Visible','on')
        end

        % Button pushed function: Harmonic4Button
        function Harmonic4ButtonPushed(app, event)
            global h4
            set(h4, 'Visible', 'on')
        end

        % Button pushed function: THDnoiseButton
        function THDnoiseButtonPushed(app, event)
            global THD_noise
            set(THD_noise, 'Visible', 'on')
        end

        % Button pushed function: THDButton
        function THDButtonPushed(app, event)
            global THD_curve          
            set(THD_curve, 'Visible', 'on')
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global h2
            global h3
            global h4
            global THD_noise
            global THD_curve
            
            set(h2,'Visible','off')
            set(h3,'Visible','off')
            set(h4,'Visible','off')
            set(THD_noise,'Visible','off')
            set(THD_curve,'Visible','off')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Phase = uiaxes(app.UIFigure);
            title(app.Phase, 'Phase Response')
            xlabel(app.Phase, 'Frequency (Hz)')
            ylabel(app.Phase, 'Phase (Degrees)')
            app.Phase.XLim = [10 20000];
            app.Phase.YLim = [-500000 500000];
            app.Phase.XScale = 'log';
            app.GroupDelay = uiaxes(app.UIFigure);
            title(app.GroupDelay, 'Group Delay')
            xlabel(app.GroupDelay, 'Frequency (Hz)')
            ylabel(app.GroupDelay, 'Group Delay (ms)')
            app.GroupDelay.XLim = [10 20000];
            app.GroupDelay.YLim = [0 100];
            app.GroupDelay.XScale = 'log';
            app.AWeightingButton = uibutton(app.UIFigure, 'push');
            app.AWeightingButton.ButtonPushedFcn = createCallbackFcn(app, @AWeightingButtonPushed, true);
            app.AWeightingButton.Text = 'A-Weighting';
            app.PlotDistortionDataButton = uibutton(app.UIFigure, 'push');
            app.PlotDistortionDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDistortionDataButtonPushed, true);
            app.PlotDistortionDataButton.Text = 'Plot Distorsion Data';
            app.PlotImpulseResponseButton = uibutton(app.UIFigure, 'push');
            app.PlotImpulseResponseButton.ButtonPushedFcn = createCallbackFcn(app, @PlotImpulseResponseButtonPushed, true);
            app.PlotImpulseResponseButton.Text = 'Plot Impulse Response';
            app.PlotWaterfallButton = uibutton(app.UIFigure, 'push');
            app.PlotWaterfallButton.ButtonPushedFcn = createCallbackFcn(app, @PlotWaterfallButtonPushed, true);
            app.PlotWaterfallButton.Text = 'Plot Waterfall';
            app.PlotRT60Button = uibutton(app.UIFigure, 'push');
            app.PlotRT60Button.ButtonPushedFcn = createCallbackFcn(app, @PlotRT60ButtonPushed, true);
            app.PlotRT60Button.Text = 'Plot RT60';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Freq = uiaxes(app.UIFigure);
            title(app.Freq, 'Frequency Response')
            xlabel(app.Freq, 'Frequency (Hz)')
            ylabel(app.Freq, 'Amplitude (dB)')
            app.Freq.XLim = [10 20000];
            app.Freq.YLim = [-100 50];
            app.Freq.XScale = 'log';
            app.Harmonic2Button = uibutton(app.UIFigure, 'push');
            app.Harmonic2Button.ButtonPushedFcn = createCallbackFcn(app, @Harmonic2ButtonPushed, true);
            app.Harmonic2Button.Text = 'Harmonic 2';
            app.Harmonic3Button = uibutton(app.UIFigure, 'push');
            app.Harmonic3Button.ButtonPushedFcn = createCallbackFcn(app, @Harmonic3ButtonPushed, true);
            app.Harmonic3Button.Text = 'Harmonic 3';
            app.Harmonic4Button = uibutton(app.UIFigure, 'push');
            app.Harmonic4Button.ButtonPushedFcn = createCallbackFcn(app, @Harmonic4ButtonPushed, true);
            app.Harmonic4Button.Text = 'Harmonic 4';
            app.THDnoiseButton = uibutton(app.UIFigure, 'push');
            app.THDnoiseButton.ButtonPushedFcn = createCallbackFcn(app, @THDnoiseButtonPushed, true);
            app.THDnoiseButton.Text = 'THD+noise';
            app.THDButton = uibutton(app.UIFigure, 'push');
            app.THDButton.ButtonPushedFcn = createCallbackFcn(app, @THDButtonPushed, true);
            app.THDButton.Text = 'THD';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DisplayIR_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end