classdef SineSweepMethod_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        text34      matlab.ui.control.Label
        text33      matlab.ui.control.Label
        text32      matlab.ui.control.Label
        text30      matlab.ui.control.Label
        text29      matlab.ui.control.Label
        text3       matlab.ui.control.Label
        text2       matlab.ui.control.Label
        Disp        matlab.ui.control.CheckBox
        OK          matlab.ui.control.Button
        Cancel      matlab.ui.control.Button
        text13      matlab.ui.control.Label
        text15      matlab.ui.control.Label
        text17      matlab.ui.control.Label
        text16      matlab.ui.control.Label
        text5       matlab.ui.control.Label
        text33_2    matlab.ui.control.Label
        text4       matlab.ui.control.Label
        text34_2    matlab.ui.control.Label
        text7       matlab.ui.control.Label
        text12      matlab.ui.control.Label
        text6       matlab.ui.control.Label
        text33_3    matlab.ui.control.Label
        text34_3    matlab.ui.control.Label
        text33_4    matlab.ui.control.Label
        text8       matlab.ui.control.Label
        text9       matlab.ui.control.Label
        text10      matlab.ui.control.Label
        Freq1       matlab.ui.control.NumericEditField
        Freq2       matlab.ui.control.NumericEditField
        Duration    matlab.ui.control.NumericEditField
        Averages    matlab.ui.control.NumericEditField
        Silences    matlab.ui.control.NumericEditField
        SampFreq    matlab.ui.control.NumericEditField
        FileName    matlab.ui.control.EditField
        DropDown    matlab.ui.control.DropDown
        DropDown_2  matlab.ui.control.DropDown
        text2_2     matlab.ui.control.Label
        Smooth      matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Cancel
        function Cancel_Callback(app, event)
            close(app.figure1);
            AS_App
        end

        % Value changed function: Disp
        function Disp_Callback(app, event)
            global verbose
            verbose=0;
%             if (get(hObject,'Value') == get(hObject,'Max'))
%             	verbose=1;
%             else
%             	verbose=0;
%             end
%             UpdatedApp.verbose=verbose;

        end

        % Button pushed function: OK
        function OK_Callback(app, event)
            value = app.DropDown.Value;
            switch value;
            case 'logarithmic' % User selects peaks.
               lin=0;
            case 'linear' % User selects membrane.
               lin=1;
            end
            time = app.Duration.Value;
            name = app.FileName.Value;
            
            f1 = app.Freq1.Value;
            f2 = app.Freq2.Value;
            number = app.Averages.Value;
            silence = app.Silences.Value;
            value = app.DropDown_2.Value;
            switch value;
            case 'mono-right' % User selects mono-left.
               mode=0;
            case 'mono-left' % User selects mono-left.
               mode=1;
            case 'stereo' % User selects stereo.
               mode=2;
            end
            fs = app.SampFreq.Value;
            value = app.Smooth.Value;
            switch value;
                case 'Base 10 1/3 Octave Smoothing'
                   smoothing=2;
                case 'Bark'
                   smoothing=1;
            end
            verbose = 0;
            assignin('base','lin',lin)
            assignin('base','name',name)
            assignin('base','f1',f1)
            assignin('base', 'f2', f2)
            assignin('base', 'time', time)
            assignin('base', 'number', number)
            assignin('base', 'silence', silence)
            assignin('base', 'mode', mode)
            assignin('base', 'fs', fs)
            assignin('base', 'verbose', verbose)
            assignin('base', 'smoothing', smoothing)
            preequal=evalin('base','preequal');
            close(app.figure1);
            setpath;
            cd(MEASURES_SWEEP_PATH);
%             app.SilenceZones.FontSize=9;
%             hey();    
%             SWEEPmeasurement(UpdatedApp);
%              SWEEPmeasurement("IR",20,2000,1,1,1,2,44100, 0,1,0);
            SWEEPmeasurement(string(name),f1,f2,time,number,silence,mode,fs,verbose,preequal,lin);
%             DisplayIR_app;
        end

        % Value changed function: Smooth
        function SmoothValueChanged(app, event)
            value = app.Smooth.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SineSweepMethod';
            app.text34 = uilabel(app.figure1);
            app.text34.Text = '';
            app.text33 = uilabel(app.figure1);
            app.text33.Text = '';
            app.text32 = uilabel(app.figure1);
            app.text32.Text = '';
            app.text30 = uilabel(app.figure1);
            app.text30.Text = '';
            app.text29 = uilabel(app.figure1);
            app.text29.Text = '';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'File name that will contain Impulse Response';
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'Type of Sweep';
            app.Disp = uicheckbox(app.figure1);
            app.Disp.ValueChangedFcn = createCallbackFcn(app, @Disp_Callback, true);
            app.Disp.Text = 'Display the menu';
            app.OK = uibutton(app.figure1, 'push');
            app.OK.ButtonPushedFcn = createCallbackFcn(app, @OK_Callback, true);
            app.OK.Text = 'O K';
            app.Cancel = uibutton(app.figure1, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @Cancel_Callback, true);
            app.Cancel.Text = 'C A N C E L';
            app.text13 = uilabel(app.figure1);
            app.text13.Tooltip = 'To prevent time-aliasing';
            app.text13.Text = '?';
            app.text15 = uilabel(app.figure1);
            app.text15.Tooltip = 'For linear, distortion noise appears everywhere on the time axis while for logarithmic, distortions appear before the impulse response in the form of harmonics';
            app.text15.Text = '?';
            app.text17 = uilabel(app.figure1);
            app.text17.Text = 'OBTAIN IMPULSE RESPONSE SETTINGS';
            app.text16 = uilabel(app.figure1);
            app.text16.Text = '* Hover your mouse over the ''?'' sign to get an explanation';
            app.text5 = uilabel(app.figure1);
            app.text5.Text = 'Final Frequency of the Sweep (Hz)';
            app.text33_2 = uilabel(app.figure1);
            app.text33_2.Text = '';
            app.text4 = uilabel(app.figure1);
            app.text4.Text = 'Initial Frequency of the Sweep (Hz)';
            app.text34_2 = uilabel(app.figure1);
            app.text34_2.Text = '';
            app.text7 = uilabel(app.figure1);
            app.text7.Text = 'Number of desired averages';
            app.text12 = uilabel(app.figure1);
            app.text12.Tooltip = 'To remove unwanted noise, which improves signal-to-noise ratio';
            app.text12.Text = '?';
            app.text6 = uilabel(app.figure1);
            app.text6.Text = 'Duration of the Sweep (s)';
            app.text33_3 = uilabel(app.figure1);
            app.text33_3.Text = '';
            app.text34_3 = uilabel(app.figure1);
            app.text34_3.Text = '';
            app.text33_4 = uilabel(app.figure1);
            app.text33_4.Text = '';
            app.text8 = uilabel(app.figure1);
            app.text8.Text = 'Duration of silence zones after each Sweep (s)';
            app.text9 = uilabel(app.figure1);
            app.text9.Text = 'Recording mode';
            app.text10 = uilabel(app.figure1);
            app.text10.Text = 'Frequency of sampling (Hz)';
            app.Freq1 = uieditfield(app.figure1, 'numeric');
            app.Freq1.Limits = [0 Inf];
            app.Freq1.Value = 20;
            app.Freq2 = uieditfield(app.figure1, 'numeric');
            app.Freq2.Limits = [0 Inf];
            app.Freq2.ValueDisplayFormat = '%.0f';
            app.Freq2.Value = 20000;
            app.Duration = uieditfield(app.figure1, 'numeric');
            app.Duration.Limits = [0 60];
            app.Duration.Value = 1;
            app.Averages = uieditfield(app.figure1, 'numeric');
            app.Averages.Limits = [0 Inf];
            app.Averages.Value = 1;
            app.Silences = uieditfield(app.figure1, 'numeric');
            app.Silences.Limits = [0 Inf];
            app.Silences.Value = 1;
            app.SampFreq = uieditfield(app.figure1, 'numeric');
            app.SampFreq.Limits = [0 384000];
            app.SampFreq.ValueDisplayFormat = '%.0f';
            app.SampFreq.Value = 44100;
            app.FileName = uieditfield(app.figure1, 'text');
            app.FileName.Value = 'IR';
            app.DropDown = uidropdown(app.figure1);
            app.DropDown.Items = {'logarithmic', 'linear'};
            app.DropDown.Value = 'logarithmic';
            app.DropDown_2 = uidropdown(app.figure1);
            app.DropDown_2.Items = {'mono-right', 'mono-left', 'stereo'};
            app.DropDown_2.Value = 'mono-left';
            app.text2_2 = uilabel(app.figure1);
            app.text2_2.Text = 'Type of Smoothing';
            app.Smooth = uidropdown(app.figure1);
            app.Smooth.Items = {'Base 10 1/3 Octave Smoothing', 'Bark'};
            app.Smooth.ValueChangedFcn = createCallbackFcn(app, @SmoothValueChanged, true);
            app.Smooth.Value = 'Bark';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SineSweepMethod_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end