classdef task1and2withgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        MinimumfrequencyHzEditFieldLabel  matlab.ui.control.Label
        MinimumfrequencyHzEditField     matlab.ui.control.NumericEditField
        MaximumfrequencyHzEditFieldLabel  matlab.ui.control.Label
        MaximumfrequencyHzEditField     matlab.ui.control.NumericEditField
        PlotthemagnitudespectrumButton  matlab.ui.control.Button
        SelectfileButton                matlab.ui.control.Button
        ORLabel                         matlab.ui.control.Label
        NumberofpointsdisplayedEditFieldLabel  matlab.ui.control.Label
        NumberofpointsdisplayedEditField  matlab.ui.control.NumericEditField
        TimetakentoplotgraphsEditFieldLabel  matlab.ui.control.Label
        TimetakentoplotgraphsEditField  matlab.ui.control.NumericEditField
        FilteringPanel                  matlab.ui.container.Panel
        FilterSwitchLabel               matlab.ui.control.Label
        FilterSwitch                    matlab.ui.control.Switch
        FiltertypeDropDownLabel         matlab.ui.control.Label
        FiltertypeDropDown              matlab.ui.control.DropDown
        WindowlengthinsamplesEditFieldLabel  matlab.ui.control.Label
        WindowlengthinsamplesEditField  matlab.ui.control.NumericEditField
        CutofffrequencySGonlyHzEditFieldLabel  matlab.ui.control.Label
        CutofffrequencySGonlyHzEditField  matlab.ui.control.NumericEditField
        SamplingfrequencyHzEditFieldLabel  matlab.ui.control.Label
        SamplingfrequencyHzEditField    matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        minFreq = 0 % min freq to be displayed on graph 
        maxFreq = 1 % max freq to be displayed on graph
        samplingFreq
        signal
        filteredSignal
    end
   
    methods (Access = private)
        
        % Moving average filter
        function MAfiltered = MA(app, y, windowSize)
            kernel = ones(windowSize,1) / windowSize;
            MAfiltered = filter(kernel, 1, y);
        end
        
        % Triangular smooth
        function tsmoothed = triangle(app, ydata, odd_width)
            half_width=(odd_width/2)-0.5;
            length_ydata=length(ydata);
            tfilter = zeros(odd_width, 1);
            tsmoothed = zeros(length_ydata, 1);
            for PositionInFilter = 1:round(odd_width/2)
                tfilter(PositionInFilter)=PositionInFilter;
            end
            for PositionInFilter = (round(odd_width/2)+1):odd_width
                tfilter(PositionInFilter)=(odd_width-PositionInFilter)+1;
            end
            for PositionInData = 1:length_ydata
                if PositionInData-half_width>=1
                    lower_width = half_width;
                else
                    lower_width = PositionInData-1;
                end
                if PositionInData+half_width<=length_ydata
                    upper_width = half_width;
                else
                    upper_width = length(ydata)-PositionInData;
                end    
                total=0;
                divide=0;
                for PositionInWidth = -lower_width:upper_width
                    total = total+ tfilter(PositionInWidth+lower_width+1)*ydata(PositionInData+PositionInWidth);
                    divide=divide+tfilter(PositionInWidth+lower_width+1);
                end
                tsmoothed(PositionInData)=total/(divide);        
            end
        end
        
         % Savitzky-Golay filter
        function SGfiltered = SG(app, y, windowSize, cutoffFreq, fs)
            M = (windowSize - 1)/2;
            cutoffRatio = cutoffFreq/fs;
            order = round(cutoffRatio*(3.2*M - 4.6) - 1);
            SGfiltered = sgolayfilt(y, order, windowSize);
        end
        
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotthemagnitudespectrumButton
        function PlotMag(app, event)
            app.minFreq = app.MinimumfrequencyHzEditField.Value;
            app.maxFreq = app.MaximumfrequencyHzEditField.Value;
            windowSize = app.WindowlengthinsamplesEditField.Value;
            cutoffFreq = app.CutofffrequencySGonlyHzEditField.Value;
            try
                [data, fs] = audioread(app.FilenameEditField.Value);
                app.samplingFreq = fs;
                app.signal = data;
                info = audioinfo(app.FilenameEditField.Value);
                NoSamples = info.TotalSamples; % number of samples in file
                app.NumberofpointsdisplayedEditField.Value = NoSamples;
                app.SamplingfrequencyHzEditField.Value = fs;
                fig = uifigure; % open up a new figure
                ax = uiaxes(fig, 'Position',[10 10 520 400]);
                ax.XScale = 'log';
                ax.XLim = [app.minFreq app.maxFreq]; % range of x axis
                ax.XGrid = 'on';
                ax.YGrid = 'on';
                ax.Title.String = 'Magnitude spectrum';
                ax.YLabel.String = 'Magnitude (dB)';
                ax.XLabel.String = 'Frequency (Hz)';
                if app.FilterSwitch.Value == '1' % filter on
                    if app.FiltertypeDropDown.Value == '1' % Moving average
                        filtSignal = MA(app, data, windowSize);
                    elseif app.FiltertypeDropDown.Value == '2' % Triangular
                        filtSignal = triangle(app, data, windowSize);
                    else % Savitzky-Golay
                        filtSignal = SG(app, data, windowSize, cutoffFreq, fs);
                    end
                    app.filteredSignal = filtSignal;
                    transform = fft(filtSignal); % fast fourier transform of signal
                    magspec = 20*log10(abs(transform)); % magnitude spectrum
                    freq = fs*(0:NoSamples-1)/NoSamples; % frequency axis 
                else % no filtering
                    transform = fft(data); % fast fourier transform of signal
                    magspec = 20*log10(abs(transform)); % magnitude spectrum
                    freq = fs*(0:NoSamples-1)/NoSamples; % frequency axis 
                end
                tic; % start stopwatch for plotting graph
                plot(ax, freq, magspec) % plots graph
                app.TimetakentoplotgraphsEditField.Value = toc; % time taken
            catch % if audioread doesn't work
                app.FilenameEditField.Value = 'Enter a valid file name.';
                % outputted values returned to defaults (0):
                app.NumberofpointsdisplayedEditField.Value = 0;
                app.TimetakentoplotgraphsEditField.Value = 0;
                app.SamplingfrequencyHzEditField.Value = 0;
            end
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [file,path] = uigetfile('*'); % open file
            if isequal(file,0)
            else
               app.FilenameEditField.Value = fullfile(path, file);
            end
            drawnow; 
            figure(app.UIFigure) % refocus app (otherwise minimised)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Tooltip = {''};
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Tooltip = {'Enter an audio file name, including the file format at the end, e.g. .wav '};
            app.FilenameEditField.Value = 'task1file.wav';
            app.MinimumfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumfrequencyHzEditFieldLabel.Text = 'Minimum frequency (Hz)';
            app.MinimumfrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumfrequencyHzEditField.Limits = [0 Inf];
            app.MinimumfrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.MinimumfrequencyHzEditField.Tooltip = {'Minimum frequency value to be displayed'};
            app.MinimumfrequencyHzEditField.Value = 10;
            app.MaximumfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumfrequencyHzEditFieldLabel.Text = 'Maximum frequency (Hz)';
            app.MaximumfrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumfrequencyHzEditField.Limits = [0 Inf];
            app.MaximumfrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.MaximumfrequencyHzEditField.Tooltip = {'Maximum frequency value to be displayed'};
            app.MaximumfrequencyHzEditField.Value = 40000;
            app.PlotthemagnitudespectrumButton = uibutton(app.UIFigure, 'push');
            app.PlotthemagnitudespectrumButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMag, true);
            app.PlotthemagnitudespectrumButton.Text = 'Plot the magnitude spectrum';
            app.SelectfileButton = uibutton(app.UIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR';
            app.NumberofpointsdisplayedEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofpointsdisplayedEditFieldLabel.Text = 'Number of points displayed';
            app.NumberofpointsdisplayedEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofpointsdisplayedEditField.ValueDisplayFormat = '%.0f';
            app.NumberofpointsdisplayedEditField.Editable = 'off';
            app.TimetakentoplotgraphsEditFieldLabel = uilabel(app.UIFigure);
            app.TimetakentoplotgraphsEditFieldLabel.Text = 'Time taken to plot graph (s)';
            app.TimetakentoplotgraphsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimetakentoplotgraphsEditField.ValueDisplayFormat = '%.3f';
            app.TimetakentoplotgraphsEditField.Editable = 'off';
            app.FilteringPanel = uipanel(app.UIFigure);
            app.FilteringPanel.Title = 'Filtering';
            app.FilterSwitchLabel = uilabel(app.FilteringPanel);
            app.FilterSwitchLabel.Text = 'Filter';
            app.FilterSwitch = uiswitch(app.FilteringPanel, 'slider');
            app.FilterSwitch.ItemsData = {'0', '1'};
            app.FilterSwitch.Value = '0';
            app.FiltertypeDropDownLabel = uilabel(app.FilteringPanel);
            app.FiltertypeDropDownLabel.Text = 'Filter type';
            app.FiltertypeDropDown = uidropdown(app.FilteringPanel);
            app.FiltertypeDropDown.Items = {'Moving Average (MA)', 'Triangular (T)', 'Savitzky-Golay (SG)'};
            app.FiltertypeDropDown.ItemsData = {'1', '2', '3'};
            app.FiltertypeDropDown.Value = '1';
            app.WindowlengthinsamplesEditFieldLabel = uilabel(app.FilteringPanel);
            app.WindowlengthinsamplesEditFieldLabel.Text = 'Window length (in samples)';
            app.WindowlengthinsamplesEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.WindowlengthinsamplesEditField.Limits = [0 750];
            app.WindowlengthinsamplesEditField.RoundFractionalValues = 'on';
            app.WindowlengthinsamplesEditField.Value = 13;
            app.CutofffrequencySGonlyHzEditFieldLabel = uilabel(app.FilteringPanel);
            app.CutofffrequencySGonlyHzEditFieldLabel.Text = 'Cutoff frequency (SG only) (Hz)';
            app.CutofffrequencySGonlyHzEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.CutofffrequencySGonlyHzEditField.Limits = [0 100000];
            app.CutofffrequencySGonlyHzEditField.ValueDisplayFormat = '%.0f';
            app.CutofffrequencySGonlyHzEditField.Value = 20000;
            app.SamplingfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingfrequencyHzEditFieldLabel.Text = 'Sampling frequency (Hz)';
            app.SamplingfrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingfrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.SamplingfrequencyHzEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task1and2withgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end