classdef task1withgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        MinimumfrequencyHzEditFieldLabel  matlab.ui.control.Label
        MinimumfrequencyHzEditField     matlab.ui.control.NumericEditField
        MaximumfrequencyHzEditFieldLabel  matlab.ui.control.Label
        MaximumfrequencyHzEditField     matlab.ui.control.NumericEditField
        PlotthemagnitudespectrumButton  matlab.ui.control.Button
        SelectfileButton                matlab.ui.control.Button
        ORLabel                         matlab.ui.control.Label
        NoofsamplesEditFieldLabel       matlab.ui.control.Label
        NoofsamplesEditField            matlab.ui.control.NumericEditField
        TimeelapsedsEditFieldLabel      matlab.ui.control.Label
        TimeelapsedsEditField           matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        minFreq = 0 % min freq to be displayed on graph 
        maxFreq = 1 % max freq to be displayed on graph
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotthemagnitudespectrumButton
        function PlotMag(app, event)
            app.minFreq = app.MinimumfrequencyHzEditField.Value;
            app.maxFreq = app.MaximumfrequencyHzEditField.Value;
            try
                tic; % start stopwatch
                [data, fs] = audioread(app.FilenameEditField.Value);
                info = audioinfo(app.FilenameEditField.Value);
                NoSamples = info.TotalSamples;
                app.NoofsamplesEditField.Value = NoSamples;
                transform = fft(data);
                magspec = 20*log10(abs(transform));
                freq = fs*(0:NoSamples-1)/NoSamples;
                app.UIAxes.XScale = 'log';
                app.UIAxes.XLim = [app.minFreq app.maxFreq];
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                plot(app.UIAxes, freq, magspec) % plots graph
                app.TimeelapsedsEditField.Value = toc; % time taken
            catch % if audioread doesn't work
                app.FilenameEditField.Value = 'Enter a valid file name.';
                app.NoofsamplesEditField.Value = 0;
                app.TimeelapsedsEditField.Value = 0;
                x = 1;
            end
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [file,path] = uigetfile('*'); % open file
            if isequal(file,0)
            else
               app.FilenameEditField.Value = fullfile(path, file);
            end
            drawnow; 
            figure(app.UIFigure) % refocus app (otherwise minimised)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Magnitude spectrum')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Magnitude (dB)')
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Tooltip = {''};
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Tooltip = {'Enter an audio file name, including the file format at the end, e.g. .wav '};
            app.FilenameEditField.Value = 'task1file.wav';
            app.MinimumfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumfrequencyHzEditFieldLabel.Text = 'Minimum frequency (Hz)';
            app.MinimumfrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumfrequencyHzEditField.Limits = [0 Inf];
            app.MinimumfrequencyHzEditField.Tooltip = {'Minimum frequency value to be displayed'};
            app.MinimumfrequencyHzEditField.Value = 10;
            app.MaximumfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumfrequencyHzEditFieldLabel.Text = 'Maximum frequency (Hz)';
            app.MaximumfrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumfrequencyHzEditField.Limits = [0 Inf];
            app.MaximumfrequencyHzEditField.Tooltip = {'Maximum frequency value to be displayed'};
            app.MaximumfrequencyHzEditField.Value = 40000;
            app.PlotthemagnitudespectrumButton = uibutton(app.UIFigure, 'push');
            app.PlotthemagnitudespectrumButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMag, true);
            app.PlotthemagnitudespectrumButton.Text = 'Plot the magnitude spectrum';
            app.SelectfileButton = uibutton(app.UIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR';
            app.NoofsamplesEditFieldLabel = uilabel(app.UIFigure);
            app.NoofsamplesEditFieldLabel.Text = 'No. of samples';
            app.NoofsamplesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofsamplesEditField.Editable = 'off';
            app.TimeelapsedsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeelapsedsEditFieldLabel.Text = 'Time elapsed (s)';
            app.TimeelapsedsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeelapsedsEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task1withgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end